classdef WeatherApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        WindSpeedLabel           matlab.ui.control.Label
        HumidityLabel            matlab.ui.control.Label
        TemperatureLabel         matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        AddCityButton            matlab.ui.control.Button
        GetInformationButton     matlab.ui.control.Button
        SelectCityDropDown       matlab.ui.control.DropDown
        SelectCityDropDownLabel  matlab.ui.control.Label
        UIAxes__WindSpeed        matlab.ui.control.UIAxes
        UIAxes__Humidity         matlab.ui.control.UIAxes
        UIAxes_Temperature       matlab.ui.control.UIAxes
    end

    
   properties (Access = private)
        APIKey = '......'; %**It is default API key from the site.
        CityList = {}; % Holding for city list
   end
   

methods (Access = private)

    function forecastData = fetchFiveDayForecast(app, city)
        apiKey = app.APIKey; % It takes directly API key from app object
        url = ['https://api.openweathermap.org/data/2.5/forecast?q=', city, '&units=metric&appid=', apiKey];
        forecastData = struct('temperature', [], 'humidity', [], 'windSpeed', [], 'time', datetime.empty);
        
        try     %**I wrote it to catch the error for example if I can't connect due to a network error.**%
            data = webread(url);
        catch Error
            disp('Failed to fetch data !');
            return;
        end
        
        try
            for i = 1:length(data.list)
                %**Kelvin to Celsius conversion is not necessary**%
                forecastData.temperature(i) = data.list(i).main.temp; 
                forecastData.humidity(i) = data.list(i).main.humidity;
                forecastData.windSpeed(i) = data.list(i).wind.speed;
                forecastData.time(i) = datetime(data.list(i).dt, 'ConvertFrom', 'posixtime');
            end
        catch Error
            disp('Error processing data.');
            return; 
        end
    end

    function processWeatherData(app, data)  %**Firstly it checks temperature data of incoming weather data**%
        if isempty(data.temperature)    %**If weather data could not be received, the function ends immediately**%
            return;
        end

        %***Extracting the data**%
        times = data.time;
        temps = data.temperature;
        humidity = data.humidity;
        windSpeed = data.windSpeed;

        %**Updating graphs titles,labels and plot.This are depends on selecting city **%
        plot(app.UIAxes_Temperature, times, temps, '-o');
        title(app.UIAxes_Temperature, ['Temperature in ', app.SelectCityDropDown.Value]);
        xlabel(app.UIAxes_Temperature, 'Time');
        ylabel(app.UIAxes_Temperature, 'Temperature (°C)');

        plot(app.UIAxes__Humidity, times, humidity, '-o');
        title(app.UIAxes__Humidity, ['Humidity in ', app.SelectCityDropDown.Value]);
        xlabel(app.UIAxes__Humidity, 'Time');
        ylabel(app.UIAxes__Humidity, 'Humidity (%)');

        plot(app.UIAxes__WindSpeed, times, windSpeed, '-o');
        title(app.UIAxes__WindSpeed, ['Wind Speed in ', app.SelectCityDropDown.Value]);
        xlabel(app.UIAxes__WindSpeed, 'Time');
        ylabel(app.UIAxes__WindSpeed, 'Wind Speed (m/s)');
        
   %**I calculated average values***%
    avg_temp = mean(temps);
    avg_humidity = mean(humidity);
    avg_windSpeed = mean(windSpeed);

    %**Update labels with average values  **%
    app.TemperatureLabel.Text = ['Temperature: ' num2str(avg_temp) ' °C'];
    app.HumidityLabel.Text = ['Humidity: ' num2str(avg_humidity) ' %'];
    app.WindSpeedLabel.Text = ['Wind Speed: ' num2str(avg_windSpeed) ' m/s'];
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddCityButton
        function AddCityButtonPushed(app, event)
             
            newCity = app.EditField.Value;  %**It assigns the new city name entered by the user to the variable.**%
            %***It checks whether the entered city name is empty or has been selected before.**%
            if ~isempty(newCity) && ~any(strcmp(app.SelectCityDropDown.Items, newCity)) 
                app.SelectCityDropDown.Items = [app.SelectCityDropDown.Items, {newCity}];
            end
            app.EditField.Value = '';
        
    
        end

        % Button pushed function: GetInformationButton
        function GetInformationButtonPushed(app, event)
            %**It retrieves information about the features of the selected city from Dropdown.*%
            city = app.SelectCityDropDown.Value;
            data = app.fetchFiveDayForecast(city);
            app.processWeatherData(data);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Temperature = uiaxes(app.UIFigure);
            title(app.UIAxes_Temperature, 'Temperature in ....')
            xlabel(app.UIAxes_Temperature, 'Time')
            ylabel(app.UIAxes_Temperature, 'Temperature (°C)')
            zlabel(app.UIAxes_Temperature, 'Z')
            app.UIAxes__Humidity = uiaxes(app.UIFigure);
            title(app.UIAxes__Humidity, 'Humidity in ....')
            xlabel(app.UIAxes__Humidity, 'Time')
            ylabel(app.UIAxes__Humidity, 'Humidity (%)')
            zlabel(app.UIAxes__Humidity, 'Z')
            app.UIAxes__WindSpeed = uiaxes(app.UIFigure);
            title(app.UIAxes__WindSpeed, 'Wind Speed in ...')
            xlabel(app.UIAxes__WindSpeed, 'Time')
            ylabel(app.UIAxes__WindSpeed, 'Wind Speed (m/s)')
            zlabel(app.UIAxes__WindSpeed, 'Z')
            app.SelectCityDropDownLabel = uilabel(app.UIFigure);
            app.SelectCityDropDownLabel.Text = 'Select City :';
            app.SelectCityDropDown = uidropdown(app.UIFigure);
            app.SelectCityDropDown.Items = {'City'};
            app.SelectCityDropDown.Value = 'City';
            app.GetInformationButton = uibutton(app.UIFigure, 'push');
            app.GetInformationButton.ButtonPushedFcn = createCallbackFcn(app, @GetInformationButtonPushed, true);
            app.GetInformationButton.Text = 'Get Information';
            app.AddCityButton = uibutton(app.UIFigure, 'push');
            app.AddCityButton.ButtonPushedFcn = createCallbackFcn(app, @AddCityButtonPushed, true);
            app.AddCityButton.Text = 'Add City';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature: ';
            app.HumidityLabel = uilabel(app.UIFigure);
            app.HumidityLabel.Text = 'Humidity: ';
            app.WindSpeedLabel = uilabel(app.UIFigure);
            app.WindSpeedLabel.Text = 'Wind Speed: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WeatherApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end